classdef project_dropdownpart < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ProjectPanel                    matlab.ui.container.Panel
        ShowSummaryReportButton         matlab.ui.control.Button
        UITable6_9                      matlab.ui.control.Table
        BasePower100MVALabel            matlab.ui.control.Label
        SummAryReportLabel              matlab.ui.control.Label
        ShowAbnormalReportButton_4      matlab.ui.control.Button
        UITable6_8                      matlab.ui.control.Table
        EditField_12                    matlab.ui.control.EditField
        ShowAbnormalReportButton_3      matlab.ui.control.Button
        UITable6_7                      matlab.ui.control.Table
        EditField_11                    matlab.ui.control.EditField
        ShowAbnormalReportButton_2      matlab.ui.control.Button
        UITable6_6                      matlab.ui.control.Table
        EditField_10                    matlab.ui.control.EditField
        ShowAbnormalReportButton        matlab.ui.control.Button
        UITable6_5                      matlab.ui.control.Table
        EditField_9                     matlab.ui.control.EditField
        AbnormalReportLabel             matlab.ui.control.Label
        ShowLineReportButton            matlab.ui.control.Button
        UITable6_4                      matlab.ui.control.Table
        EditField_8                     matlab.ui.control.EditField
        ShowLoadReportButton            matlab.ui.control.Button
        UITable6_3                      matlab.ui.control.Table
        EditField_7                     matlab.ui.control.EditField
        ShowGeneratorReportButton       matlab.ui.control.Button
        UITable6_2                      matlab.ui.control.Table
        EditField_6                     matlab.ui.control.EditField
        ShowBusReportButton             matlab.ui.control.Button
        UITable6                        matlab.ui.control.Table
        EditField_5                     matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        DescriptionofTypeLabel          matlab.ui.control.Label
        ChooseTheNumberofBusesDropDown  matlab.ui.control.DropDown
        ChooseTheNumberofBusesDropDownLabel  matlab.ui.control.Label
        EditField_4                     matlab.ui.control.EditField
        UITable5                        matlab.ui.control.Table
        ShowComparisonButton            matlab.ui.control.Button
        PerformAnalysisButton_2         matlab.ui.control.Button
        EditField_3                     matlab.ui.control.EditField
        EditField_2                     matlab.ui.control.EditField
        EditField                       matlab.ui.control.EditField
        UITable4                        matlab.ui.control.Table
        PerformAnalysisButton           matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        ReadBusDataButton               matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        ReadLineDataButton              matlab.ui.control.Button
        UITable2                        matlab.ui.control.Table
        UITable3                        matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
    end


    methods (Access = private)
        
        function Y = ybusppg(app,num)
  
%             input(prompt,'numbers of buses');
 value = str2num(app.ChooseTheNumberofBusesDropDown.Value);
            switch value
                case 4
                    tl= readtable("Book1.xlsx","Sheet",2);
                case 14
                    tl=readtable("Book1.xlsx","Sheet",4);
                case 30
                    tl=readtable("Book1.xlsx","Sheet",6);
                case 57
                    tl=readtable("Book1.xlsx","Sheet",8);
            end
            app.UITable2.Data=tl;
            %             t.Properties.VariableNames{1}='x data';
            %             t.Properties.VariableNames{2}='y data';
%             app.UITable2.ColumnName=tl.Properties.VariableNames;
            linedata=table2array(tl);
            fb = linedata(:,1);             % From bus number...
            tb = linedata(:,2);             % To bus number...
            R = linedata(:,3);              % Resistance, R...
            X = linedata(:,4);              % Reactance, X...
            B = linedata(:,5);              % Ground Admittance, B/2...
            a = linedata(:,6);              % Tap setting value..
            z = R + 1i*X;                    % z matrix...
            y = 1./z;                       % To get inverse of each element...
            B = 1i*B;                        % Make B imaginary...
            
            nb = max(max(fb),max(tb));      % No. of buses...
            nl = length(fb);                % No. of branches...
            Y = zeros(nb,nb);               % Initialise YBus...
            
            % Formation of the Off Diagonal Elements...
            for k = 1:nl
                Y(fb(k),tb(k)) = Y(fb(k),tb(k)) - y(k)/a(k);
                Y(tb(k),fb(k)) = Y(fb(k),tb(k));
            end
            
            % Formation of Diagonal Elements....
            for m = 1:nb
                for n = 1:nl
                    if fb(n) == m
                        Y(m,m) = Y(m,m) + y(n)/(a(n)^2) + B(n);
                    elseif tb(n) == m
                        Y(m,m) = Y(m,m) + y(n) + B(n);
                    end
                end
            end
        end
        
        
        function results = func2(app)
            
        end
        % Polar to Rectangular Conversion
        function rect = pol2rect(app,r,theta)
            rect = r.*cos(theta) + 1i*r.*sin(theta);
            
        end
        
        function [Pi,Qi ,Pg, Qg ,Pl, Ql, Sij, Lij] = loadflow(app,nb,V,del,bMVA)
             value = str2num(app.ChooseTheNumberofBusesDropDown.Value); 
            switch value
                case 4
                    t=readtable("Book1.xlsx","Sheet",1);
                case 14
                    t=readtable("Book1.xlsx","Sheet",3);
                case 30
                    t=readtable("Book1.xlsx","Sheet",5);
                case 57
                    t=readtable("Book1.xlsx","Sheet",7);
            end
            %t=readtable("Book1.xlsx","Sheet",1);
            app.UITable.Data=t;
            busdata=table2array(t);
            n_col=10;
            n_row=length(busdata(:,1));
            nb=n_row;
             switch value
                case 4
                    tl=readtable("Book1.xlsx","Sheet",2);
                case 14
                    tl=readtable("Book1.xlsx","Sheet",4);
                case 30
                    tl=readtable("Book1.xlsx","Sheet",6);
                case 57
                    tl=readtable("Book1.xlsx","Sheet",8);
            end
            %tl=readtable("Book1.xlsx","Sheet",2);
            app.UITable2.Data=tl;
            linedata=table2array(tl);
            Y = ybusppg(app,nb);                % Calling Ybus program..
            %             linedata = linedatas(nb);       % Get linedats..
            %             busdata = busdatas(nb);         % Get busdatas..
            Vm = pol2rect(app,V,del);           % Converting polar to rectangular..
            Del = 180/pi*del;               % Bus Voltage Angles in Degree...
            fb = linedata(:,1);             % From bus number...
            tb = linedata(:,2);             % To bus number...
            nl = length(fb);                % No. of Branches..
            Pl = busdata(:,7);              % PLi..
            Ql = busdata(:,8);              % QLi..
            
            Iij = zeros(nb,nb);             % Branch current from i to j bus
            Sij = zeros(nb,nb);             % Branch flow from i to j bus
            Si = zeros(nb,1);
            
            % Bus Current Injections..
            I = Y*Vm;
            % Im = abs(I);
            % Ia = angle(I);
            
            %Line Current Flows..
            for m = 1:nl
                p = fb(m);
                q = tb(m);
                Iij(p,q) = -(Vm(p) - Vm(q))*Y(p,q);   % Y(m,n) = -y(m,n)..
                Iij(q,p) = -Iij(p,q);
            end
            %Iijm = abs(Iij);
            %Iija = angle(Iij);
            
            % Line Power Flows..
            for m = 1:nb
                for n = 1:nb
                    if m ~= n
                        Sij(m,n) = Vm(m)*conj(Iij(m,n))*bMVA;   %conj: complex conjugate
                    end
                end
            end
            Pij = real(Sij);
            Qij = imag(Sij);
            
            % Line Losses..
            Lij = zeros(nl,1);
            for m = 1:nl
                p = fb(m);
                q = tb(m);
                Lij(m) = Sij(p,q) + Sij(q,p);
            end
            Lpij = real(Lij);
            Lqij = imag(Lij);
            
            % Bus Power Injections..
            for i = 1:nb
                for k = 1:nb
                    Si(i) = Si(i) + conj(Vm(i))* Vm(k)*Y(i,k)*bMVA;
                end
            end
            Pi = real(Si);
            Qi = -imag(Si);
            Pg = Pi+Pl;
            Qg = Qi+Ql;
            for m = 1:nl
                p = fb(m);
                q = tb(m);
                Lij(m) = Sij(p,q) + Sij(q,p);
            end
            Lpij = real(Lij);
            Lqij = imag(Lij);
            for m = 1:nl
                p = fb(m);
                q = tb(m);
                nr1={num2str(p) num2str(q) Pij(p,q) Qij(p,q) num2str(q) num2str(p) Pij(q,p) Qij(q,p) Lpij(m) Lqij(m)};
                app.UITable3.Data=[app.UITable3.Data;nr1];
                %                 app.Data.LoadUI=app.UITable3.Data;
                %                 app.UITable3=app.UITable3.Data;
                %                 writetable();
                
            end
           
        end
        function [Pi,Qi ,Pg, Qg ,Pl, Ql, Sij, Lij] = loadflow2(app,nb,V,del,bMVA)
             value = str2num(app.ChooseTheNumberofBusesDropDown.Value); 
            switch value
                case 4
                    t=readtable("Book1.xlsx","Sheet",1);
                case 14
                    t=readtable("Book1.xlsx","Sheet",3);
                case 30
                    t=readtable("Book1.xlsx","Sheet",5);
                case 57
                    t=readtable("Book1.xlsx","Sheet",7);
            end
%             t=readtable("Book1.xlsx","Sheet",1);
            app.UITable.Data=t;
            %             t.Properties.VariableNames{1}='x data';
            %             t.Properties.VariableNames{2}='y data';
%             app.UITable.ColumnName=t.Properties.VariableNames;
            busdata=table2array(t);
            n_col=10;
            n_row=length(busdata(:,1));
            nb=n_row;
             switch value
                case 4
                    tl=readtable("Book1.xlsx","Sheet",2);
                case 14
                    tl=readtable("Book1.xlsx","Sheet",4);
                case 30
                    tl=readtable("Book1.xlsx","Sheet",6);
                case 57
                    tl=readtable("Book1.xlsx","Sheet",8);
            end
%             tl=readtable("Book1.xlsx","Sheet",2);
            app.UITable2.Data=tl;
            %             t.Properties.VariableNames{1}='x data';
            %             t.Properties.VariableNames{2}='y data';
%             app.UITable2.ColumnName=tl.Properties.VariableNames;
            linedata=table2array(tl);
            Y = ybusppg(app,nb);                % Calling Ybus program..
            %             linedata = linedatas(nb);       % Get linedats..
            %             busdata = busdatas(nb);         % Get busdatas..
            Vm = pol2rect(app,V,del);           % Converting polar to rectangular..
            Del = 180/pi*del;               % Bus Voltage Angles in Degree...
            fb = linedata(:,1);             % From bus number...
            tb = linedata(:,2);             % To bus number...
            nl = length(fb);                % No. of Branches..
            Pl = busdata(:,7);              % PLi..
            Ql = busdata(:,8);              % QLi..
            
            Iij = zeros(nb,nb);             % Branch current from i to j bus
            Sij = zeros(nb,nb);             % Branch flow from i to j bus
            Si = zeros(nb,1);
            
            % Bus Current Injections..
            I = Y*Vm;
            % Im = abs(I);
            % Ia = angle(I);
            
            %Line Current Flows..
            for m = 1:nl
                p = fb(m);
                q = tb(m);
                Iij(p,q) = -(Vm(p) - Vm(q))*Y(p,q);   % Y(m,n) = -y(m,n)..
                Iij(q,p) = -Iij(p,q);
            end
            %Iijm = abs(Iij);
            %Iija = angle(Iij);
            
            % Line Power Flows..
            for m = 1:nb
                for n = 1:nb
                    if m ~= n
                        Sij(m,n) = Vm(m)*conj(Iij(m,n))*bMVA;   %conj: complex conjugate
                    end
                end
            end
            Pij = real(Sij);
            Qij = imag(Sij);
            
            % Line Losses..
            Lij = zeros(nl,1);
            for m = 1:nl
                p = fb(m);
                q = tb(m);
                Lij(m) = Sij(p,q) + Sij(q,p);
            end
            Lpij = real(Lij);
            Lqij = imag(Lij);
            
            % Bus Power Injections..
            for i = 1:nb
                for k = 1:nb
                    Si(i) = Si(i) + conj(Vm(i))* Vm(k)*Y(i,k)*bMVA;
                end
            end
            Pi = real(Si);
            Qi = -imag(Si);
            Pg = Pi+Pl;
            Qg = Qi+Ql;
            for m = 1:nl
                p = fb(m);
                q = tb(m);
                Lij(m) = Sij(p,q) + Sij(q,p);
            end
            Lpij = real(Lij);
            Lqij = imag(Lij);
            for m = 1:nl
                p = fb(m);
                q = tb(m);
                nr={p q Pij(p,q) Qij(p,q) q p Pij(q,p) Qij(q,p) Lpij(m) Lqij(m)};
                nr1={num2str(p) num2str(q) Pij(p,q) Qij(p,q) num2str(q) num2str(p) Pij(q,p) Qij(q,p) Lpij(m) Lqij(m)};
                app.UITable4.Data=[app.UITable4.Data;nr1];
                
            end
           
        end
        
        function [iter,telapsed,mismatch,v_error,dat1,Sij,Lij] = newton_raphson(app,nbus)
            % Program for Newton-Raphson Load Flow Analysis..
            tstart = tic ;
            value = str2num(app.ChooseTheNumberofBusesDropDown.Value); 
            switch value
                case 4
                    tl=readtable("Book1.xlsx","Sheet",2);
                case 14
                    tl=readtable("Book1.xlsx","Sheet",4);
                case 30
                    tl=readtable("Book1.xlsx","Sheet",6);
                case 57
                    tl=readtable("Book1.xlsx","Sheet",8);
            end
%             tl=readtable("Book1.xlsx","Sheet",2);
            app.UITable2.Data=tl;
%             app.UITable2.ColumnName=tl.Properties.VariableNames;
            linedata=table2array(tl);
             switch value
                case 4
                    t=readtable("Book1.xlsx","Sheet",1);
                case 14
                    t=readtable("Book1.xlsx","Sheet",3);
                case 30
                    t=readtable("Book1.xlsx","Sheet",5);
                case 57
                    t=readtable("Book1.xlsx","Sheet",7);
            end

            busdata=table2array(t);
            %             n_col=10;
            n_row=length(busdata(:,1));
            %             nbus=n_row;
            Y = ybusppg(app,nbus);             % Calling ybusppg.m to get Y-Bus Matrix..
            %             busdata = busdatas(nbus);      % Calling busdatas..
            bMVA = 100;                    % Base MVA..
            bus = busdata(:,1);            % Bus Number..
            type = busdata(:,2);           % Type of Bus 1-Slack, 2-PV, 3-PQ..
            V = busdata(:,3);              % Specified Voltage..
            del = busdata(:,4);            % Voltage Angle..
            Pg = busdata(:,5)/bMVA;        % PGi..
            Qg = busdata(:,6)/bMVA;        % QGi..
            Pl = busdata(:,7)/bMVA;        % PLoad..
            Ql = busdata(:,8)/bMVA;        % QLoad..
            Qmin = busdata(:,9)/bMVA;      % Minimum Reactive Power Limit..
            Qmax = busdata(:,10)/bMVA;     % Maximum Reactive Power Limit..
            Psch = Pg - Pl;                  % Pi = PGi - PLi..
            Qsch = Qg - Ql;                  % Qi = QGi - QLi..

            G = real(Y);                   % Conductance matrix..
            B = imag(Y);                   % Susceptance matrix..

            pv = find(type == 2 | type == 1);   % PV Buses..
            pq = find(type == 3);               % PQ Buses..
            npv = length(pv);                   % No. of PV buses..
            npq = length(pq);                   % No. of PQ buses..

            Tol = 1;
            iter = 0;
            p_mismatch = zeros(20,1);
            v_error1 = zeros(20,1);
            while (Tol>1e-5)                   % Iteration starting..

                Pi = zeros(nbus,1);
                Qi = zeros(nbus,1);
                % Calculate P and Q
                for i = 1:nbus
                    for k = 1:nbus
                        Pi(i) = Pi(i) + V(i)* V(k)*(G(i,k)*cos(del(i)-del(k)) + B(i,k)*sin(del(i)-del(k)));
                        Qi(i) = Qi(i) + V(i)* V(k)*(G(i,k)*sin(del(i)-del(k)) - B(i,k)*cos(del(i)-del(k)));
                    end
                end
                dPa = Psch-Pi;
                dQa = Qsch-Qi;
                k = 1;
                dQ = zeros(npq,1);
                for i = 1:nbus
                    if type(i) == 3
                        dQ(k,1) = dQa(i);
                        k = k+1;
                    end
                end
                dP = dPa(2:nbus);
                M = [dP; dQ];       % Mismatch Vector

                % Jacobian
                % J11 - Derivative of Real Power Injections with Angles..
                J11 = zeros(nbus-1,nbus-1);
                for i = 1:nbus-1
                    m = i+1;
                    for k = 1:nbus-1
                        n = k+1;
                        if n == m
                            for n = 1:nbus
                                J11(i,k) = J11(i,k) + V(m)* V(n)*(-G(m,n)*sin(del(m)-del(n)) + B(m,n)*cos(del(m)-del(n)));
                            end
                            J11(i,k) = J11(i,k) - V(m)^2*B(m,m);
                        else
                            J11(i,k) = V(m)* V(n)*(G(m,n)*sin(del(m)-del(n)) - B(m,n)*cos(del(m)-del(n)));
                        end
                    end
                end
                % J12 - Derivative of Real Power Injections with V..
                J12 = zeros(nbus-1,npq);
                for i = 1:(nbus-1)
                    m = i+1;
                    for k = 1:npq
                        n = pq(k);
                        if n == m
                            for n = 1:nbus
                                J12(i,k) = J12(i,k) + V(n)*(G(m,n)*cos(del(m)-del(n)) + B(m,n)*sin(del(m)-del(n)));
                            end
                            J12(i,k) = J12(i,k) + V(m)*G(m,m);
                        else
                            J12(i,k) = V(m)*(G(m,n)*cos(del(m)-del(n)) + B(m,n)*sin(del(m)-del(n)));
                        end
                    end
                end

                % J21 - Derivative of Reactive Power Injections with Angles..
                J21 = zeros(npq,nbus-1);
                for i = 1:npq
                    m = pq(i);
                    for k = 1:nbus-1
                        n = k+1;
                        if n == m
                            for n = 1:nbus
                                J21(i,k) = J21(i,k) + V(m)* V(n)*(G(m,n)*cos(del(m)-del(n)) + B(m,n)*sin(del(m)-del(n)));
                            end
                            J21(i,k) = J21(i,k) - V(m)^2*G(m,m);
                        else
                            J21(i,k) = V(m)* V(n)*(-G(m,n)*cos(del(m)-del(n)) - B(m,n)*sin(del(m)-del(n)));
                        end
                    end
                end

                % J22 - Derivative of Reactive Power Injections with V..
                J22 = zeros(npq,npq);
                for i = 1:npq
                    m = pq(i);
                    for k = 1:npq
                        n = pq(k);
                        if n == m
                            for n = 1:nbus
                                J22(i,k) = J22(i,k) + V(n)*(G(m,n)*sin(del(m)-del(n)) - B(m,n)*cos(del(m)-del(n)));
                            end
                            J22(i,k) = J22(i,k) - V(m)*B(m,m);
                        else
                            J22(i,k) = V(m)*(G(m,n)*sin(del(m)-del(n)) - B(m,n)*cos(del(m)-del(n)));
                        end
                    end
                end

                J = [J11 J12; J21 J22];     % Jacobian Matrix..

                X = inv(J)*M;           % Correction Vector
                dTh = X(1:nbus-1);      % Change in Voltage Angle..
                dV = X(nbus:end);       % Change in Voltage Magnitude..

                % Updating State Vectors..
                del(2:nbus) = dTh + del(2:nbus);    % Voltage Angle..
                k = 1;
                for i = 2:nbus
                    if type(i) == 3
                        V(i) = V(i)+dV(k);        % Voltage Magnitude..
                        k = k+1;
                    end
                end

                iter = iter + 1;
                Tol = max(abs(M));                  % Tolerance..
                p_mismatch(iter,1) = Tol;
                v_error1(iter,1)= max(abs(dV));
            end
            telapsed = toc(tstart);
            Del = 180/pi*del;


            [Pi,Qi ,Pg, Qg ,Pl, Ql, Sij, Lij]= loadflow(app,nbus,V,del,bMVA);% Calling Loadflow.m..
            dat1 = zeros(nbus,9);

            for i = 1:nbus
                dat1(i,1) = i;
                dat1(i,2) = V(i,1);
                dat1(i,3) = Del(i,1);
                dat1(i,4) = Pi(i,1);
                dat1(i,5) = Qi(i,1);
                dat1(i,6) = Pg(i,1);
                dat1(i,7) = Qg(i,1);
                dat1(i,8) = Pl(i,1);
                dat1(i,9) = Ql(i,1);
            end

            mismatch = p_mismatch(1:iter,1);
            v_error = v_error1(1:iter,1);

        end


        function [iter,telapsed,mismatch,v_error,dat2,Sij,Lij] = snr2(app,nbus)
            %simplified Newton Raphson Load Flow analysis
            tstart = tic;
            value = str2num(app.ChooseTheNumberofBusesDropDown.Value);
             switch value
                case 4
                    t=readtable("Book1.xlsx","Sheet",1);
                case 14
                    t=readtable("Book1.xlsx","Sheet",3);
                case 30
                    t=readtable("Book1.xlsx","Sheet",5);
                case 57
                    t=readtable("Book1.xlsx","Sheet",7);
            end
%             t=readtable("Book1.xlsx","Sheet",1);
            app.UITable.Data=t;
%             app.UITable.ColumnName=t.Properties.VariableNames;
            busdata=table2array(t);
            n_col=10;
            n_row=length(busdata(:,1));
            nbus=n_row;
            Y = ybusppg(app,nbus);             % Calling ybusppg.m to get Y-Bus Matrix..
%             busdata = busdatas(nbus);      % Calling busdatas..
            BMva = 100;                    % Base MVA..
            
            type = busdata(:,2);           % Type of Bus 1-Slack, 2-PV, 3-PQ..
            V = busdata(:,3);              % Specified Voltage..
            del = busdata(:,4);            % Voltage Angle..
            Pg = busdata(:,5)/BMva;        % PGi..
            Qg = busdata(:,6)/BMva;        % QGi..
            Pl = busdata(:,7)/BMva;        % PLi..
            Ql = busdata(:,8)/BMva;        % QLi..
            
            Psch = Pg - Pl;                   % Pi = PGi - PLi..
            Qsch = Qg - Ql;                   % Qi = QGi - QLi...
            pv = find(type == 2 );            % PV Buses..
            pq = find(type == 3);               % PQ Buses..
            npv = length(pv);                   % No. of PV buses..
            npq = length(pq);                   % No. of PQ buses..
            
            S_sch=Psch+1j*Qsch;                  %scheduled power...
            ang_S=angle(S_sch);
            abs_S=abs(S_sch);
            
            tol=1;
            iter=0;
            pv1 = pv - 1;
            g_mismatch = zeros(20,1);
            v_error1 = zeros(20,1);
            
            while(tol>1e-5)
                G = zeros(nbus-1,1);
                H = zeros(nbus-1,1);
                for k=1:nbus-1
                    m=k+1;
                    G(k,1)= (abs_S(m)/V(m))*cos(-ang_S(m)+del(m));
                    H(k,1)= (abs_S(m)/V(m))*sin(-ang_S(m)+del(m));
                    for i=1:nbus
                        G(k,1)= G(k,1)-abs(Y(m,i))*V(i)*cos(angle(Y(m,i))+del(i));
                        H(k,1)= H(k,1)-abs(Y(m,i))*V(i)*sin(angle(Y(m,i))+del(i));
                    end
                    
                end
                
                H(pv1(:),:)=[];
                
                M=[G;H];            %current mismatch vector
                
                % forming jaccobian matrix
                
                J11=zeros(nbus-1,nbus-1);
                J12=zeros(nbus-1,nbus-1);
                J21=zeros(nbus-1,nbus-1);
                J22=zeros(nbus-1,nbus-1);
                for k=1:(nbus-1)
                    m = k+1;
                    for i=1:(nbus-1)
                        n = i+1;
                        if m==n
                            J11(k,i)= (-V(m)*abs(Y(m,m)))*sin(angle(Y(m,m))+del(m))+abs_S(m)/V(m)*sin(-ang_S(m)+del(m));
                            J12(k,i)= abs(Y(n,n))*cos(angle(Y(n,n))+del(n))+abs_S(n)/(V(n)^2)*cos(-ang_S(n)+del(n));
                            J21(k,i)= (V(m)*abs(Y(m,m)))*cos(angle(Y(m,m))+del(m))-(abs_S(m)/V(m)*cos(-ang_S(m)+del(m)));
                            J22(k,i)= abs(Y(m,m))*sin(angle(Y(m,m))+del(m))+abs_S(m)/(V(m)^2)*sin(-ang_S(m)+del(m));
                        else
                            J11(k,i)=(-V(n)*abs(Y(m,n)))*sin(angle(Y(m,n))+del(n));
                            J12(k,i)=abs(Y(m,n))*cos(angle(Y(m,n))+del(n));
                            J21(k,i)=V(n)*abs(Y(m,n))*cos(angle(Y(m,n))+del(n));
                            J22(k,i)=abs(Y(m,n))*sin(angle(Y(m,n))+del(n));
                        end
                    end
                end
                J12(:,pv1(:))=[];
                J21(pv1(:),:) = [];
                J22(:,pv1(:))=[];
                J22(pv1(:),:) = [];
                
                J=[J11 J12;J21 J22];
                
                
                X = J\M;               % Correction Vector
                dV = X(nbus:end);       % Change in Voltage Magnitude..
                
                
                % Updating State Vectors...
                del(2:nbus) = X(1:nbus-1)+del(2:nbus);    % Voltage Angle..
                
                for i = 1:npq
                    m = pq(i);
                    V(m) = V(m)+dV(i);        % Voltage Magnitude..
                end
                %updating reactive power of generator(Qg) for voltage controlled bus(PVbus)
                
                for k=1:npv
                    m = pv(k);
                    Qsch(m)=0;
                    for i=1:nbus
                        Qsch(m)=Qsch(m)-V(m)*V(i)*abs(Y(m,i))*sin(angle(Y(m,i))+del(i)-del(m));
                    end
                end
                S_sch=Psch+1j*Qsch;
                ang_S=angle(S_sch);
                abs_S=abs(S_sch);
                iter = iter + 1;
                tol = max(abs(M));                  % Tolerance..
                g_mismatch(iter,1) = tol;
                v_error1(iter,1) = max(abs(dV));
            end
            telapsed = toc(tstart);
            Del = 180/pi*del;
            mismatch = g_mismatch(1:iter,1);
            v_error = v_error1(1:iter,1);
            
            [Pi,Qi ,Pg, Qg ,Pl, Ql, Sij, Lij]= loadflow2(app,nbus,V,del,BMva);
            dat2 = zeros(nbus,9);
            
            for i = 1:nbus
                dat2(i,1) = i;
                dat2(i,2) = V(i,1);
                dat2(i,3) = Del(i,1);
                dat2(i,4) = Pi(i,1);
                dat2(i,5) = Qi(i,1);
                dat2(i,6) = Pg(i,1);
                dat2(i,7) = Qg(i,1);
                dat2(i,8) = Pl(i,1);
                dat2(i,9) = Ql(i,1);
            end
            
        end
        function [Pi,Qi ,Pg, Qg ,Pl, Ql, Sij, Lij] = loadflow_1(app,nb,V,del,bMVA)
            value = str2num(app.ChooseTheNumberofBusesDropDown.Value);
            switch value
                case 4
                    t=readtable("Book1.xlsx","Sheet",1);
                case 14
                    t=readtable("Book1.xlsx","Sheet",3);
                case 30
                    t=readtable("Book1.xlsx","Sheet",5);
                case 57
                    t=readtable("Book1.xlsx","Sheet",7);
            end
%             t=readtable("Book1.xlsx","Sheet",1);
            app.UITable.Data=t;
            busdata=table2array(t);
            n_col=10;
            n_row=length(busdata(:,1));
            nb=n_row;
            switch value
                case 4
                    tl=readtable("Book1.xlsx","Sheet",2);
                case 14
                    tl=readtable("Book1.xlsx","Sheet",4);
                case 30
                    tl=readtable("Book1.xlsx","Sheet",6);
                case 57
                    tl=readtable("Book1.xlsx","Sheet",8);
            end
%             tl=readtable("Book1.xlsx","Sheet",2);
            app.UITable2.Data=tl;
            linedata=table2array(tl);
            Y = ybusppg(app,nb);                % Calling Ybus program..
            %             linedata = linedatas(nb);       % Get linedats..
            %             busdata = busdatas(nb);         % Get busdatas..
            Vm = pol2rect(app,V,del);           % Converting polar to rectangular..
            Del = 180/pi*del;               % Bus Voltage Angles in Degree...
            fb = linedata(:,1);             % From bus number...
            tb = linedata(:,2);             % To bus number...
            nl = length(fb);                % No. of Branches..
            Pl = busdata(:,7);              % PLi..
            Ql = busdata(:,8);              % QLi..
            
            Iij = zeros(nb,nb);             % Branch current from i to j bus
            Sij = zeros(nb,nb);             % Branch flow from i to j bus
            Si = zeros(nb,1);
            
            % Bus Current Injections..
            I = Y*Vm;
            % Im = abs(I);
            % Ia = angle(I);
            
            %Line Current Flows..
            for m = 1:nl
                p = fb(m);
                q = tb(m);
                Iij(p,q) = -(Vm(p) - Vm(q))*Y(p,q);   % Y(m,n) = -y(m,n)..
                Iij(q,p) = -Iij(p,q);
            end
            %Iijm = abs(Iij);
            %Iija = angle(Iij);
            
            % Line Power Flows..
            for m = 1:nb
                for n = 1:nb
                    if m ~= n
                        Sij(m,n) = Vm(m)*conj(Iij(m,n))*bMVA;   %conj: complex conjugate
                    end
                end
            end
            Pij = real(Sij);
            Qij = imag(Sij);
            
            % Line Losses..
            Lij = zeros(nl,1);
            for m = 1:nl
                p = fb(m);
                q = tb(m);
                Lij(m) = Sij(p,q) + Sij(q,p);
            end
            Lpij = real(Lij);
            Lqij = imag(Lij);
            
            % Bus Power Injections..
            for i = 1:nb
                for k = 1:nb
                    Si(i) = Si(i) + conj(Vm(i))* Vm(k)*Y(i,k)*bMVA;
                end
            end
            Pi = real(Si);
            Qi = -imag(Si);
            Pg = Pi+Pl;
            Qg = Qi+Ql;
            for m = 1:nl
                p = fb(m);
                q = tb(m);
                Lij(m) = Sij(p,q) + Sij(q,p);
            end
            Lpij = real(Lij);
            Lqij = imag(Lij);   
        end
        function [Pi,Qi ,Pg, Qg ,Pl, Ql, Sij, Lij] = loadflow2_2(app,nb,V,del,bMVA)
            value = str2num(app.ChooseTheNumberofBusesDropDown.Value);
            switch value
                case 4
                    t=readtable("Book1.xlsx","Sheet",1);
                case 14
                    t=readtable("Book1.xlsx","Sheet",3);
                case 30
                    t=readtable("Book1.xlsx","Sheet",5);
                case 57
                    t=readtable("Book1.xlsx","Sheet",7);
            end
%             t=readtable("Book1.xlsx","Sheet",1);
            app.UITable.Data=t;
            busdata=table2array(t);
            n_col=10;
            n_row=length(busdata(:,1));
            nb=n_row;
            switch value
                case 4
                    tl=readtable("Book1.xlsx","Sheet",2);
                case 14
                    tl=readtable("Book1.xlsx","Sheet",4);
                case 30
                    tl=readtable("Book1.xlsx","Sheet",6);
                case 57
                    tl=readtable("Book1.xlsx","Sheet",8);
            end
%             tl=readtable("Book1.xlsx","Sheet",2);
            app.UITable2.Data=tl;
            linedata=table2array(tl);
            Y = ybusppg(app,nb);                % Calling Ybus program..
            %             linedata = linedatas(nb);       % Get linedats..
            %             busdata = busdatas(nb);         % Get busdatas..
            Vm = pol2rect(app,V,del);           % Converting polar to rectangular..
            Del = 180/pi*del;               % Bus Voltage Angles in Degree...
            fb = linedata(:,1);             % From bus number...
            tb = linedata(:,2);             % To bus number...
            nl = length(fb);                % No. of Branches..
            Pl = busdata(:,7);              % PLi..
            Ql = busdata(:,8);              % QLi..
            
            Iij = zeros(nb,nb);             % Branch current from i to j bus
            Sij = zeros(nb,nb);             % Branch flow from i to j bus
            Si = zeros(nb,1);
            
            % Bus Current Injections..
            I = Y*Vm;
            % Im = abs(I);
            % Ia = angle(I);
            
            %Line Current Flows..
            for m = 1:nl
                p = fb(m);
                q = tb(m);
                Iij(p,q) = -(Vm(p) - Vm(q))*Y(p,q);   % Y(m,n) = -y(m,n)..
                Iij(q,p) = -Iij(p,q);
            end
            %Iijm = abs(Iij);
            %Iija = angle(Iij);
            
            % Line Power Flows..
            for m = 1:nb
                for n = 1:nb
                    if m ~= n
                        Sij(m,n) = Vm(m)*conj(Iij(m,n))*bMVA;   %conj: complex conjugate
                    end
                end
            end
            Pij = real(Sij);
            Qij = imag(Sij);
            
            % Line Losses..
            Lij = zeros(nl,1);
            for m = 1:nl
                p = fb(m);
                q = tb(m);
                Lij(m) = Sij(p,q) + Sij(q,p);
            end
            Lpij = real(Lij);
            Lqij = imag(Lij);
            
            % Bus Power Injections..
            for i = 1:nb
                for k = 1:nb
                    Si(i) = Si(i) + conj(Vm(i))* Vm(k)*Y(i,k)*bMVA;
                end
            end
            Pi = real(Si);
            Qi = -imag(Si);
            Pg = Pi+Pl;
            Qg = Qi+Ql;
            for m = 1:nl
                p = fb(m);
                q = tb(m);
                Lij(m) = Sij(p,q) + Sij(q,p);
            end
            Lpij = real(Lij);
            Lqij = imag(Lij);
           
        end
        
        function [iter,telapsed,mismatch,v_error,dat1,Sij1,Lij1] = newton_raphson2(app,nbus)
            % Program for Newton-Raphson Load Flow Analysis..
            tstart = tic ;
            value = str2num(app.ChooseTheNumberofBusesDropDown.Value);
            switch value
                case 4
                    tl=readtable("Book1.xlsx","Sheet",2);
                case 14
                    tl=readtable("Book1.xlsx","Sheet",4);
                case 30
                    tl=readtable("Book1.xlsx","Sheet",6);
                case 57
                    tl=readtable("Book1.xlsx","Sheet",8);
            end
%             tl=readtable("Book1.xlsx","Sheet",2);
            app.UITable2.Data=tl;
%             app.UITable2.ColumnName=tl.Properties.VariableNames;
            linedata=table2array(tl);
            switch value
                case 4
                    t=readtable("Book1.xlsx","Sheet",1);
                case 14
                    t=readtable("Book1.xlsx","Sheet",3);
                case 30
                    t=readtable("Book1.xlsx","Sheet",5);
                case 57
                    t=readtable("Book1.xlsx","Sheet",7);
            end
%             t=readtable("Book1.xlsx","Sheet",1);
            app.UITable.Data=t;
%             app.UITable.ColumnName=t.Properties.VariableNames;
            busdata=table2array(t);
%             n_col=10;
            n_row=length(busdata(:,1));
%             nbus=n_row;
            Y = ybusppg(app,nbus);             % Calling ybusppg.m to get Y-Bus Matrix..
            %             busdata = busdatas(nbus);      % Calling busdatas..
            bMVA = 100;                    % Base MVA..
            bus = busdata(:,1);            % Bus Number..
            type = busdata(:,2);           % Type of Bus 1-Slack, 2-PV, 3-PQ..
            V = busdata(:,3);              % Specified Voltage..
            del = busdata(:,4);            % Voltage Angle..
            Pg = busdata(:,5)/bMVA;        % PGi..
            Qg = busdata(:,6)/bMVA;        % QGi..
            Pl = busdata(:,7)/bMVA;        % PLoad..
            Ql = busdata(:,8)/bMVA;        % QLoad..
            Qmin = busdata(:,9)/bMVA;      % Minimum Reactive Power Limit..
            Qmax = busdata(:,10)/bMVA;     % Maximum Reactive Power Limit..
            Psch = Pg - Pl;                  % Pi = PGi - PLi..
            Qsch = Qg - Ql;                  % Qi = QGi - QLi..
            
            G = real(Y);                   % Conductance matrix..
            B = imag(Y);                   % Susceptance matrix..
            
            pv = find(type == 2 | type == 1);   % PV Buses..
            pq = find(type == 3);               % PQ Buses..
            npv = length(pv);                   % No. of PV buses..
            npq = length(pq);                   % No. of PQ buses..
            
            Tol = 1;
            iter = 0;
            p_mismatch = zeros(20,1);
            v_error1 = zeros(20,1);
            while (Tol>1e-5)                   % Iteration starting..
                
                Pi = zeros(nbus,1);
                Qi = zeros(nbus,1);
                % Calculate P and Q
                for i = 1:nbus
                    for k = 1:nbus
                        Pi(i) = Pi(i) + V(i)* V(k)*(G(i,k)*cos(del(i)-del(k)) + B(i,k)*sin(del(i)-del(k)));
                        Qi(i) = Qi(i) + V(i)* V(k)*(G(i,k)*sin(del(i)-del(k)) - B(i,k)*cos(del(i)-del(k)));
                    end
                end
                dPa = Psch-Pi;
                dQa = Qsch-Qi;
                k = 1;
                dQ = zeros(npq,1);
                for i = 1:nbus
                    if type(i) == 3
                        dQ(k,1) = dQa(i);
                        k = k+1;
                    end
                end
                dP = dPa(2:nbus);
                M = [dP; dQ];       % Mismatch Vector
                
                % Jacobian
                % J11 - Derivative of Real Power Injections with Angles..
                J11 = zeros(nbus-1,nbus-1);
                for i = 1:nbus-1
                    m = i+1;
                    for k = 1:nbus-1
                        n = k+1;
                        if n == m
                            for n = 1:nbus
                                J11(i,k) = J11(i,k) + V(m)* V(n)*(-G(m,n)*sin(del(m)-del(n)) + B(m,n)*cos(del(m)-del(n)));
                            end
                            J11(i,k) = J11(i,k) - V(m)^2*B(m,m);
                        else
                            J11(i,k) = V(m)* V(n)*(G(m,n)*sin(del(m)-del(n)) - B(m,n)*cos(del(m)-del(n)));
                        end
                    end
                end
                % J12 - Derivative of Real Power Injections with V..
                J12 = zeros(nbus-1,npq);
                for i = 1:(nbus-1)
                    m = i+1;
                    for k = 1:npq
                        n = pq(k);
                        if n == m
                            for n = 1:nbus
                                J12(i,k) = J12(i,k) + V(n)*(G(m,n)*cos(del(m)-del(n)) + B(m,n)*sin(del(m)-del(n)));
                            end
                            J12(i,k) = J12(i,k) + V(m)*G(m,m);
                        else
                            J12(i,k) = V(m)*(G(m,n)*cos(del(m)-del(n)) + B(m,n)*sin(del(m)-del(n)));
                        end
                    end
                end
                
                % J21 - Derivative of Reactive Power Injections with Angles..
                J21 = zeros(npq,nbus-1);
                for i = 1:npq
                    m = pq(i);
                    for k = 1:nbus-1
                        n = k+1;
                        if n == m
                            for n = 1:nbus
                                J21(i,k) = J21(i,k) + V(m)* V(n)*(G(m,n)*cos(del(m)-del(n)) + B(m,n)*sin(del(m)-del(n)));
                            end
                            J21(i,k) = J21(i,k) - V(m)^2*G(m,m);
                        else
                            J21(i,k) = V(m)* V(n)*(-G(m,n)*cos(del(m)-del(n)) - B(m,n)*sin(del(m)-del(n)));
                        end
                    end
                end
                
                % J22 - Derivative of Reactive Power Injections with V..
                J22 = zeros(npq,npq);
                for i = 1:npq
                    m = pq(i);
                    for k = 1:npq
                        n = pq(k);
                        if n == m
                            for n = 1:nbus
                                J22(i,k) = J22(i,k) + V(n)*(G(m,n)*sin(del(m)-del(n)) - B(m,n)*cos(del(m)-del(n)));
                            end
                            J22(i,k) = J22(i,k) - V(m)*B(m,m);
                        else
                            J22(i,k) = V(m)*(G(m,n)*sin(del(m)-del(n)) - B(m,n)*cos(del(m)-del(n)));
                        end
                    end
                end
                
                J = [J11 J12; J21 J22];     % Jacobian Matrix..
                
                X = inv(J)*M;           % Correction Vector
                dTh = X(1:nbus-1);      % Change in Voltage Angle..
                dV = X(nbus:end);       % Change in Voltage Magnitude..
                
                % Updating State Vectors..
                del(2:nbus) = dTh + del(2:nbus);    % Voltage Angle..
                k = 1;
                for i = 2:nbus
                    if type(i) == 3
                        V(i) = V(i)+dV(k);        % Voltage Magnitude..
                        k = k+1;
                    end
                end
                
                iter = iter + 1;
                Tol = max(abs(M));                  % Tolerance..
                p_mismatch(iter,1) = Tol;
                v_error1(iter,1)= max(abs(dV));
            end
            telapsed = toc(tstart);
            Del = 180/pi*del;
            
            
            [Pi,Qi ,Pg, Qg ,Pl, Ql, Sij1, Lij1]= loadflow_1(app,nbus,V,del,bMVA);% Calling Loadflow.m..
            dat1 = zeros(nbus,9);
            
            for i = 1:nbus
                dat1(i,1) = i;
                dat1(i,2) = V(i,1);
                dat1(i,3) = Del(i,1);
                dat1(i,4) = Pi(i,1);
                dat1(i,5) = Qi(i,1);
                dat1(i,6) = Pg(i,1);
                dat1(i,7) = Qg(i,1);
                dat1(i,8) = Pl(i,1);
                dat1(i,9) = Ql(i,1);
            end
            
            mismatch = p_mismatch(1:iter,1);
            v_error = v_error1(1:iter,1);
            
        end


        function [iter,telapsed,mismatch,v_error ,dat2, Sij, Lij] = snr2_2(app,nbus)
            %simplified Newton Raphson Load Flow analysis
            tstart = tic;
            value = str2num(app.ChooseTheNumberofBusesDropDown.Value);
            switch value
                case 4
                    t=readtable("Book1.xlsx","Sheet",1);
                case 14
                    t=readtable("Book1.xlsx","Sheet",3);
                case 30
                    t=readtable("Book1.xlsx","Sheet",5);
                case 57
                    t=readtable("Book1.xlsx","Sheet",7);
            end
%             t=readtable("Book1.xlsx","Sheet",1);
            app.UITable.Data=t;
%             app.UITable.ColumnName=t.Properties.VariableNames;
            busdata=table2array(t);
            n_col=10;
            n_row=length(busdata(:,1));
            nbus=n_row;
            Y = ybusppg(app,nbus);             % Calling ybusppg.m to get Y-Bus Matrix..
%             busdata = busdatas(nbus);      % Calling busdatas..
            BMva = 100;                    % Base MVA..
            
            type = busdata(:,2);           % Type of Bus 1-Slack, 2-PV, 3-PQ..
            V = busdata(:,3);              % Specified Voltage..
            del = busdata(:,4);            % Voltage Angle..
            Pg = busdata(:,5)/BMva;        % PGi..
            Qg = busdata(:,6)/BMva;        % QGi..
            Pl = busdata(:,7)/BMva;        % PLi..
            Ql = busdata(:,8)/BMva;        % QLi..
            
            Psch = Pg - Pl;                   % Pi = PGi - PLi..
            Qsch = Qg - Ql;                   % Qi = QGi - QLi...
            pv = find(type == 2 );            % PV Buses..
            pq = find(type == 3);               % PQ Buses..
            npv = length(pv);                   % No. of PV buses..
            npq = length(pq);                   % No. of PQ buses..
            
            S_sch=Psch+1j*Qsch;                  %scheduled power...
            ang_S=angle(S_sch);
            abs_S=abs(S_sch);
            
            tol=1;
            iter=0;
            pv1 = pv - 1;
            g_mismatch = zeros(20,1);
            v_error1 = zeros(20,1);
            
            while(tol>1e-5)
                G = zeros(nbus-1,1);
                H = zeros(nbus-1,1);
                for k=1:nbus-1
                    m=k+1;
                    G(k,1)= (abs_S(m)/V(m))*cos(-ang_S(m)+del(m));
                    H(k,1)= (abs_S(m)/V(m))*sin(-ang_S(m)+del(m));
                    for i=1:nbus
                        G(k,1)= G(k,1)-abs(Y(m,i))*V(i)*cos(angle(Y(m,i))+del(i));
                        H(k,1)= H(k,1)-abs(Y(m,i))*V(i)*sin(angle(Y(m,i))+del(i));
                    end
                    
                end
                
                H(pv1(:),:)=[];
                
                M=[G;H];            %current mismatch vector
                
                % forming jaccobian matrix
                
                J11=zeros(nbus-1,nbus-1);
                J12=zeros(nbus-1,nbus-1);
                J21=zeros(nbus-1,nbus-1);
                J22=zeros(nbus-1,nbus-1);
                for k=1:(nbus-1)
                    m = k+1;
                    for i=1:(nbus-1)
                        n = i+1;
                        if m==n
                            J11(k,i)= (-V(m)*abs(Y(m,m)))*sin(angle(Y(m,m))+del(m))+abs_S(m)/V(m)*sin(-ang_S(m)+del(m));
                            J12(k,i)= abs(Y(n,n))*cos(angle(Y(n,n))+del(n))+abs_S(n)/(V(n)^2)*cos(-ang_S(n)+del(n));
                            J21(k,i)= (V(m)*abs(Y(m,m)))*cos(angle(Y(m,m))+del(m))-(abs_S(m)/V(m)*cos(-ang_S(m)+del(m)));
                            J22(k,i)= abs(Y(m,m))*sin(angle(Y(m,m))+del(m))+abs_S(m)/(V(m)^2)*sin(-ang_S(m)+del(m));
                        else
                            J11(k,i)=(-V(n)*abs(Y(m,n)))*sin(angle(Y(m,n))+del(n));
                            J12(k,i)=abs(Y(m,n))*cos(angle(Y(m,n))+del(n));
                            J21(k,i)=V(n)*abs(Y(m,n))*cos(angle(Y(m,n))+del(n));
                            J22(k,i)=abs(Y(m,n))*sin(angle(Y(m,n))+del(n));
                        end
                    end
                end
                J12(:,pv1(:))=[];
                J21(pv1(:),:) = [];
                J22(:,pv1(:))=[];
                J22(pv1(:),:) = [];
                
                J=[J11 J12;J21 J22];
                
                
                X = J\M;               % Correction Vector
                dV = X(nbus:end);       % Change in Voltage Magnitude..
                
                
                % Updating State Vectors...
                del(2:nbus) = X(1:nbus-1)+del(2:nbus);    % Voltage Angle..
                
                for i = 1:npq
                    m = pq(i);
                    V(m) = V(m)+dV(i);        % Voltage Magnitude..
                end
                %updating reactive power of generator(Qg) for voltage controlled bus(PVbus)
                
                for k=1:npv
                    m = pv(k);
                    Qsch(m)=0;
                    for i=1:nbus
                        Qsch(m)=Qsch(m)-V(m)*V(i)*abs(Y(m,i))*sin(angle(Y(m,i))+del(i)-del(m));
                    end
                end
                S_sch=Psch+1j*Qsch;
                ang_S=angle(S_sch);
                abs_S=abs(S_sch);
                iter = iter + 1;
                tol = max(abs(M));                  % Tolerance..
                g_mismatch(iter,1) = tol;
                v_error1(iter,1) = max(abs(dV));
            end
            telapsed = toc(tstart);
            Del = 180/pi*del;
            mismatch = g_mismatch(1:iter,1);
            v_error = v_error1(1:iter,1);
            
            [Pi,Qi ,Pg, Qg ,Pl, Ql, Sij, Lij]= loadflow2_2(app,nbus,V,del,BMva);
            dat2 = zeros(nbus,9);
            
            for i = 1:nbus
                dat2(i,1) = i;
                dat2(i,2) = V(i,1);
                dat2(i,3) = Del(i,1);
                dat2(i,4) = Pi(i,1);
                dat2(i,5) = Qi(i,1);
                dat2(i,6) = Pg(i,1);
                dat2(i,7) = Qg(i,1);
                dat2(i,8) = Pl(i,1);
                dat2(i,9) = Ql(i,1);
            end
            
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReadBusDataButton
        function ReadBusDataButtonPushed(app, event)
            value = str2num(app.ChooseTheNumberofBusesDropDown.Value);
             switch value
                case 4
                    t=readtable("Book1.xlsx","Sheet",1);
                case 14
                    t=readtable("Book1.xlsx","Sheet",3);
                case 30
                    t=readtable("Book1.xlsx","Sheet",5);
                case 57
                    t=readtable("Book1.xlsx","Sheet",7);
            end
%         t=readtable("Book1.xlsx","Sheet",1);
        app.UITable.Data=t;
        %             t.Properties.VariableNames{1}='x data';
        %             t.Properties.VariableNames{2}='y data';
%         app.UITable.ColumnName=t.Properties.VariableNames;
        busdata=table2array(t);
%         n_col=10;
%         n_row=length(busdata(:,1));
%         app.resultEditField.Value=n_row;
        end

        % Callback function: ReadLineDataButton, UIFigure
        function Line_DataButtonPushed(app, event)
            value = str2num(app.ChooseTheNumberofBusesDropDown.Value);
            switch value
                case 4
                    tl=readtable("Book1.xlsx","Sheet",2);
                case 14
                    tl=readtable("Book1.xlsx","Sheet",4);
                case 30
                    tl=readtable("Book1.xlsx","Sheet",6);
                case 57
                    tl=readtable("Book1.xlsx","Sheet",8);
            end
%         tl=readtable("Book1.xlsx","Sheet",2);
        app.UITable2.Data=tl;
%         app.UITable2.ColumnName=tl.Properties.VariableNames;
        linedata=table2array(tl);
%         nl_col=7;
%         nl_row=length(linedata(:,1));
%         p=ybusppg(app,nl_row);
        %         app.rowEditField.Value=abs(p(1,1));
%         [a,b,c,d,e]=newton_raphson(app,nl_row);
%         app.rowEditField.Value=b;
        
        end

        % Callback function
        function LineButtonPushed(app, event)
        %             loadflow(app,nb,V,del,bMVA);
        value = str2num(app.ChooseTheNumberofBusesDropDown.Value);
        switch value
                case 4
                    tl=readtable("Book1.xlsx","Sheet",2);
                case 14
                    tl=readtable("Book1.xlsx","Sheet",4);
                case 30
                    tl=readtable("Book1.xlsx","Sheet",6);
                case 57
                    tl=readtable("Book1.xlsx","Sheet",8);
            end
%         tl=readtable("Book1.xlsx","Sheet",2);
        app.UITable2.Data=tl;
        %             t.Properties.VariableNames{1}='x data';
        %             t.Properties.VariableNames{2}='y data';
%         app.UITable2.ColumnName=tl.Properties.VariableNames;
        linedata=table2array(tl);
        nl_col=7;
        nl_row=length(linedata(:,1));
        switch value
                case 4
                    t=readtable("Book1.xlsx","Sheet",1);
                case 14
                    t=readtable("Book1.xlsx","Sheet",3);
                case 30
                    t=readtable("Book1.xlsx","Sheet",5);
                case 57
                    t=readtable("Book1.xlsx","Sheet",7);
            end
%         t=readtable("Book1.xlsx","Sheet",1);
        app.UITable.Data=t;
        t.Properties.VariableNames{1}='x data';
        t.Properties.VariableNames{2}='y data';
%         app.UITable.ColumnName=t.Properties.VariableNames;
        busdata=table2array(t);
        n_col=10;
        n_row=length(busdata(:,1));
        nb=n_row;
        %             Y = ybusppg(app,nb);                % Calling Ybus program..
        %             %             linedata = linedatas(nb);       % Get linedats..
        %             %             busdata = busdatas(nb);         % Get busdatas..
        V = busdata(:,3);              % Specified Voltage..
        del = busdata(:,4);            % Voltage Angle..
        bMVA=100;
        %             loadflow(app,nb,V,del,bMVA);
        
        
        end

        % Button pushed function: PerformAnalysisButton
        function PerformAnalysisButtonPushed(app, event)
            value = str2num(app.ChooseTheNumberofBusesDropDown.Value);
            switch value
                case 4
                    tl=readtable("Book1.xlsx","Sheet",2);
                case 14
                    tl=readtable("Book1.xlsx","Sheet",4);
                case 30
                    tl=readtable("Book1.xlsx","Sheet",6);
                case 57
                    tl=readtable("Book1.xlsx","Sheet",8);
            end
%             tl=readtable("Book1.xlsx","Sheet",2);
            app.UITable2.Data=tl;
            %             t.Properties.VariableNames{1}='x data';
            %             t.Properties.VariableNames{2}='y data';
%             app.UITable2.ColumnName=tl.Properties.VariableNames;
            linedata=table2array(tl);
            nl_col=7;
            nl_row=length(linedata(:,1));
            switch value
                case 4
                    t=readtable("Book1.xlsx","Sheet",1);
                case 14
                    t=readtable("Book1.xlsx","Sheet",3);
                case 30
                    t=readtable("Book1.xlsx","Sheet",5);
                case 57
                    t=readtable("Book1.xlsx","Sheet",7);
            end
%             t=readtable("Book1.xlsx","Sheet",1);
            app.UITable.Data=t;
            t.Properties.VariableNames{1}='x data';
            t.Properties.VariableNames{2}='y data';
%             app.UITable.ColumnName=t.Properties.VariableNames;
            busdata=table2array(t);
            n_col=10;
            n_row=length(busdata(:,1));
            nb=n_row;
            %             Y = ybusppg(app,nb);                % Calling Ybus program..
            %             %             linedata = linedatas(nb);       % Get linedats..
            %             %             busdata = busdatas(nb);         % Get busdatas..
            V = busdata(:,3);              % Specified Voltage..
            del = busdata(:,4);            % Voltage Angle..
            bMVA=100;
            snr2(app,nb);
        end

        % Callback function
        function CompareButtonPushed(app, event)
%             t=readtable("Book1.xlsx","Sheet",1);
%             app.UITable.Data=t;
%             busdata=table2array(t);
%             n_row=length(busdata(:,1));
%             nbus=n_row;
%             [iter,telapsed,mismatch,v_error] = newton_raphson(nbus);
%             [iter2,telapsed2,mismatch2,v_error2] = snr2(nbus);
        end

        % Button pushed function: PerformAnalysisButton_2
        function PerformAnalysisButton_2Pushed2(app, event)
            value = str2num(app.ChooseTheNumberofBusesDropDown.Value);
            switch value
                case 4
                    t=readtable("Book1.xlsx","Sheet",1);
                case 14
                    t=readtable("Book1.xlsx","Sheet",3);
                case 30
                    t=readtable("Book1.xlsx","Sheet",5);
                case 57
                    t=readtable("Book1.xlsx","Sheet",7);
            end
% t=readtable("Book1.xlsx","Sheet",1);
            app.UITable.Data=t;
%             app.UITable.ColumnName=t.Properties.VariableNames;
            busdata=table2array(t);
            n_row=length(busdata(:,1));
            nbus=n_row;
            newton_raphson(app,nbus);
        end

        % Button pushed function: ShowComparisonButton
        function ShowComparisonButtonPushed(app, event)
            value = str2num(app.ChooseTheNumberofBusesDropDown.Value);
            switch value
                case 4
                    t=readtable("Book1.xlsx","Sheet",1);
                case 14
                    t=readtable("Book1.xlsx","Sheet",3);
                case 30
                    t=readtable("Book1.xlsx","Sheet",5);
                case 57
                    t=readtable("Book1.xlsx","Sheet",7);
            end
%             t=readtable("Book1.xlsx","Sheet",1);
            app.UITable.Data=t;
            busdata=table2array(t);
            n_row=length(busdata(:,1));
            nbus=n_row;
            [iter,telapsed,mismatch,v_error] = newton_raphson2(app,nbus);
            [iter2,telapsed2,mismatch2,v_error2] = snr2_2(app,nbus);
            n1 = 1:iter;
            n2 = 1:iter2;
            n = 1:10^4;
            nr_flop_J = 10*n.^2;
            snr_flop_J = 22*(n-2);
            nr_flop_m = 6*n;
            snr_flop_m = 4*n+4;
%             app.UITable4.Data=[app.UITable4.Data;nr1];
            app.UITable5.ColumnEditable=true;
            p=char("Newton Raphson");
            q=char("Simplified Newton Raphson");
            nr1={p num2str(iter) telapsed telapsed/iter};
            app.UITable5.Data=[app.UITable5.Data;nr1];
            nr2={q num2str(iter2) telapsed2 telapsed2/iter2};
            app.UITable5.Data=[app.UITable5.Data;nr2];
            bar(app.UIAxes,[telapsed/iter telapsed2/iter2],'g',"LineWidth",1.5);
        end

        % Value changed function: ChooseTheNumberofBusesDropDown
        function ChooseTheNumberofBusesDropDownValueChanged(app, event)

%           value = str2num(app.ChooseTheNumberofBusesDropDown.Value);
            
        end

        % Button pushed function: ShowBusReportButton
        function ShowBusReportButtonPushed(app, event)
            nbus=str2num(app.ChooseTheNumberofBusesDropDown.Value);
            
            [iter,telapsed,mismatch,v_error,dat1,Sij1,Lij1] = newton_raphson(app,nbus);
            [iter2,telapsed2,mismatch2,v_error2,dat2,Sij2,Lij2] = snr2(app,nbus);

            for k=1:nbus
                nr1={num2str(dat1(k,1)) dat1(k,2) dat1(k,3) dat1(k,6) dat1(k,7) dat1(k,8) dat1(k,9)};
%                 fprintf('%7g',dat1(k,1)); fprintf('        %4.2f',dat1(k,2)); fprintf('        %.2f',dat1(k,3)); fprintf('        %.2f',dat1(k,6));fprintf('     %4.2f',dat1(k,7));
%                 fprintf('        %4.2f',dat1(k,8));fprintf('        %.3f',dat1(k,9));
%                 fprintf('\n');
                %disp('--------------------------------------------------------------------------------------');
                app.UITable6.Data=[app.UITable6.Data;nr1];
            end
        end

        % Button pushed function: ShowGeneratorReportButton
        function ShowGeneratorReportButtonPushed(app, event)
            nbus=str2num(app.ChooseTheNumberofBusesDropDown.Value);
            [iter,telapsed,mismatch,v_error,dat1,Sij1,Lij1] = newton_raphson(app,nbus);
            [iter2,telapsed2,mismatch2,v_error2,dat2,Sij2,Lij2] = snr2(app,nbus);
            
            switch nbus
                case 4
                    t=readtable("Book1.xlsx","Sheet",1);
                case 14
                    t=readtable("Book1.xlsx","Sheet",3);
                case 30
                    t=readtable("Book1.xlsx","Sheet",5);
                case 57
                    t=readtable("Book1.xlsx","Sheet",7);
            end
            busdata=table2array(t);
            
            switch nbus
                case 4
                    tl=readtable("Book1.xlsx","Sheet",2);
                case 14
                    tl=readtable("Book1.xlsx","Sheet",4);
                case 30
                    tl=readtable("Book1.xlsx","Sheet",6);
                case 57
                    tl=readtable("Book1.xlsx","Sheet",8);
            end
            linedata=table2array(tl);
            
            V=busdata(:,3);
            type=busdata(:,2);
            bus = busdata(:,1);
            bMVA = 100;
            Qmin = busdata(:,9)/bMVA;
            Qmax = busdata(:,10)/bMVA;
            fb = linedata(:,1);             % From bus number...
            tb = linedata(:,2);             % To bus number...
            nl = length(fb);                % No. of Branches..
            loading_limit=linedata(:,end);
            generator_buses=(type==2);

            for kk=1:nbus
                if type(kk)==2
                    nr1={num2str(sum(generator_buses(1:kk))) num2str(kk) dat1(kk,6) dat1(kk,7) sqrt(dat1(kk,6)^2+dat1(kk,7)^2) 100*abs(dat1(kk,6))*sign(dat1(kk,7))/sqrt(dat1(kk,6)^2+dat1(kk,7)^2) busdata(kk,9) busdata(kk,10)};
                    app.UITable6_2.Data=[app.UITable6_2.Data;nr1];
                end
            end
        end

        % Button pushed function: ShowLoadReportButton
        function ShowLoadReportButtonPushed(app, event)
            nbus=str2num(app.ChooseTheNumberofBusesDropDown.Value);
            [iter,telapsed,mismatch,v_error,dat1,Sij1,Lij1] = newton_raphson(app,nbus);
            [iter2,telapsed2,mismatch2,v_error2,dat2,Sij2,Lij2] = snr2(app,nbus);
            
            switch nbus
                case 4
                    t=readtable("Book1.xlsx","Sheet",1);
                case 14
                    t=readtable("Book1.xlsx","Sheet",3);
                case 30
                    t=readtable("Book1.xlsx","Sheet",5);
                case 57
                    t=readtable("Book1.xlsx","Sheet",7);
            end
            busdata=table2array(t);
            
            switch nbus
                case 4
                    tl=readtable("Book1.xlsx","Sheet",2);
                case 14
                    tl=readtable("Book1.xlsx","Sheet",4);
                case 30
                    tl=readtable("Book1.xlsx","Sheet",6);
                case 57
                    tl=readtable("Book1.xlsx","Sheet",8);
            end
            linedata=table2array(tl);
            
            V=busdata(:,3);
            type=busdata(:,2);
            bus = busdata(:,1);
            bMVA = 100;
            Qmin = busdata(:,9)/bMVA;
            Qmax = busdata(:,10)/bMVA;
            fb = linedata(:,1);             % From bus number...
            tb = linedata(:,2);             % To bus number...
            nl = length(fb);                % No. of Branches..
            loading_limit=linedata(:,end);
            load_buses=(busdata(:,7)~=0) & (busdata(:,8)~=0);

            for kk=1:nbus
                if load_buses(kk)==1
                    nr1={num2str(sum(load_buses(1:kk))) num2str(kk) dat1(kk,8) dat1(kk,9) sqrt(dat1(kk,8)^2+dat1(kk,9)^2)...
                        100*abs(dat1(kk,8))*sign(dat1(kk,9))/sqrt(dat1(kk,8)^2+dat1(kk,9)^2)};
                    app.UITable6_3.Data=[app.UITable6_3.Data;nr1];
                end
            end
        end

        % Button pushed function: ShowLineReportButton
        function ShowLineReportButtonPushed(app, event)
            nbus=str2num(app.ChooseTheNumberofBusesDropDown.Value);
            [iter,telapsed,mismatch,v_error,dat1,Sij1,Lij1] = newton_raphson(app,nbus);
            [iter2,telapsed2,mismatch2,v_error2,dat2,Sij2,Lij2] = snr2(app,nbus);

            switch nbus
                case 4
                    t=readtable("Book1.xlsx","Sheet",1);
                case 14
                    t=readtable("Book1.xlsx","Sheet",3);
                case 30
                    t=readtable("Book1.xlsx","Sheet",5);
                case 57
                    t=readtable("Book1.xlsx","Sheet",7);
            end
            busdata=table2array(t);

            switch nbus
                case 4
                    tl=readtable("Book1.xlsx","Sheet",2);
                case 14
                    tl=readtable("Book1.xlsx","Sheet",4);
                case 30
                    tl=readtable("Book1.xlsx","Sheet",6);
                case 57
                    tl=readtable("Book1.xlsx","Sheet",8);
            end
            linedata=table2array(tl);

            V=busdata(:,3);
            type=busdata(:,2);
            bus = busdata(:,1);
            bMVA = 100;
            Qmin = busdata(:,9)/bMVA;
            Qmax = busdata(:,10)/bMVA;
            fb = linedata(:,1);             % From bus number...
            tb = linedata(:,2);             % To bus number...
            nl = length(fb);                % No. of Branches..
            loading_limit=linedata(:,end);
            load_buses=(busdata(:,7)~=0) & (busdata(:,8)~=0);

            for kk=1:nl
                p = fb(kk); q = tb(kk);
                nr1={num2str(kk) num2str(p) num2str(q) real(Sij1(p,q))+real(Lij1(kk)) imag(Sij1(p,q))+imag(Lij1(kk)) ...
                    abs(Sij1(p,q))+abs(Lij1(kk)) 100*abs(real(Sij1(p,q))+real(Lij1(kk)))*sign(imag(Sij1(p,q))+imag(Lij1(kk)))/(abs(Sij1(p,q))+abs(Lij1(kk)))...
                    100*abs(Sij1(p,q))/linedata(kk,7) real(Lij1(kk)) imag(Lij1(kk))};
                app.UITable6_4.Data=[app.UITable6_4.Data;nr1];
            end
        end

        % Button pushed function: ShowAbnormalReportButton
        function ShowAbnormalReportButtonPushed(app, event)
            nbus=str2num(app.ChooseTheNumberofBusesDropDown.Value);
            [iter,telapsed,mismatch,v_error,dat1,Sij1,Lij1] = newton_raphson(app,nbus);
            [iter2,telapsed2,mismatch2,v_error2,dat2,Sij2,Lij2] = snr2(app,nbus);

            switch nbus
                case 4
                    t=readtable("Book1.xlsx","Sheet",1);
                case 14
                    t=readtable("Book1.xlsx","Sheet",3);
                case 30
                    t=readtable("Book1.xlsx","Sheet",5);
                case 57
                    t=readtable("Book1.xlsx","Sheet",7);
            end
            busdata=table2array(t);

            switch nbus
                case 4
                    tl=readtable("Book1.xlsx","Sheet",2);
                case 14
                    tl=readtable("Book1.xlsx","Sheet",4);
                case 30
                    tl=readtable("Book1.xlsx","Sheet",6);
                case 57
                    tl=readtable("Book1.xlsx","Sheet",8);
            end
            linedata=table2array(tl);

            V=busdata(:,3);
            type=busdata(:,2);
            bus = busdata(:,1);
            bMVA = 100;
            Qmin = busdata(:,9)/bMVA;
            Qmax = busdata(:,10)/bMVA;
            fb = linedata(:,1);             % From bus number...
            tb = linedata(:,2);             % To bus number...
            nl = length(fb);                % No. of Branches..
            loading_limit=linedata(:,end);
            load_buses=(busdata(:,7)~=0) & (busdata(:,8)~=0);
            
            for m = 1:nbus
                if dat1(m,2)>V(m)
                    nr1={num2str(m) 'Overloaded' (dat1(m,2)-V(m))*100/V(m)};
                    app.UITable6_5.Data=[app.UITable6_5.Data;nr1];
                end
            end
        end

        % Button pushed function: ShowAbnormalReportButton_2
        function ShowAbnormalReportButton_2Pushed(app, event)
            nbus=str2num(app.ChooseTheNumberofBusesDropDown.Value);
            [iter,telapsed,mismatch,v_error,dat1,Sij1,Lij1] = newton_raphson(app,nbus);
            [iter2,telapsed2,mismatch2,v_error2,dat2,Sij2,Lij2] = snr2(app,nbus);

            switch nbus
                case 4
                    t=readtable("Book1.xlsx","Sheet",1);
                case 14
                    t=readtable("Book1.xlsx","Sheet",3);
                case 30
                    t=readtable("Book1.xlsx","Sheet",5);
                case 57
                    t=readtable("Book1.xlsx","Sheet",7);
            end
            busdata=table2array(t);

            switch nbus
                case 4
                    tl=readtable("Book1.xlsx","Sheet",2);
                case 14
                    tl=readtable("Book1.xlsx","Sheet",4);
                case 30
                    tl=readtable("Book1.xlsx","Sheet",6);
                case 57
                    tl=readtable("Book1.xlsx","Sheet",8);
            end
            linedata=table2array(tl);

            V=busdata(:,3);
            type=busdata(:,2);
            bus = busdata(:,1);
            bMVA = 100;
            Qmin = busdata(:,9)/bMVA;
            Qmax = busdata(:,10)/bMVA;
            fb = linedata(:,1);             % From bus number...
            tb = linedata(:,2);             % To bus number...
            nl = length(fb);                % No. of Branches..
            loading_limit=linedata(:,end);
            load_buses=(busdata(:,7)~=0) & (busdata(:,8)~=0);
            generator_buses=(type==2);
            for mm = 1:nbus
                if type(mm)==2
                    if dat1(mm,7)<busdata(mm,9)
                        if busdata(mm,9)==0
                            nr1={num2str(sum(generator_buses(1:mm))) num2str(bus(mm)) dat1(mm,6) dat1(mm,7)...
                                Qmin(mm)*bMVA Qmax(mm)*bMVA abs((dat1(mm,7)-busdata(mm,9)))};
                            app.UITable6_6.Data=[app.UITable6_6.Data;nr1];
                        else
                            nr1={num2str(sum(generator_buses(1:mm))) num2str(bus(mm)) dat1(mm,6) dat1(mm,7)...
                                Qmin(mm)*bMVA Qmax(mm)*bMVA abs((dat1(mm,7)-busdata(mm,9))*100/busdata(mm,9))};
                            app.UITable6_6.Data=[app.UITable6_6.Data;nr1];
                        end
                    elseif dat1(mm,7)>busdata(mm,10)
                        if busdata(mm,10)==0
                            nr1={num2str(sum(generator_buses(1:mm))) num2str(bus(mm)) dat1(mm,6) dat1(mm,7)...
                                Qmin(mm)*bMVA Qmax(mm)*bMVA abs((dat1(mm,7)-busdata(mm,10)))};
                            app.UITable6_6.Data=[app.UITable6_6.Data;nr1];
                        else
                            nr1={num2str(sum(generator_buses(1:mm))) num2str(bus(mm)) dat1(mm,6) dat1(mm,7)...
                                Qmin(mm)*bMVA Qmax(mm)*bMVA abs((dat1(mm,7)-busdata(mm,10))*100/busdata(mm,10))};
                            app.UITable6_6.Data=[app.UITable6_6.Data;nr1];
                        end
                    end
                end
            end
        end

        % Button pushed function: ShowAbnormalReportButton_3
        function ShowAbnormalReportButton_3Pushed(app, event)
            nbus=str2num(app.ChooseTheNumberofBusesDropDown.Value);
            [iter,telapsed,mismatch,v_error,dat1,Sij1,Lij1] = newton_raphson(app,nbus);
            [iter2,telapsed2,mismatch2,v_error2,dat2,Sij2,Lij2] = snr2(app,nbus);

            switch nbus
                case 4
                    t=readtable("Book1.xlsx","Sheet",1);
                case 14
                    t=readtable("Book1.xlsx","Sheet",3);
                case 30
                    t=readtable("Book1.xlsx","Sheet",5);
                case 57
                    t=readtable("Book1.xlsx","Sheet",7);
            end
            busdata=table2array(t);

            switch nbus
                case 4
                    tl=readtable("Book1.xlsx","Sheet",2);
                case 14
                    tl=readtable("Book1.xlsx","Sheet",4);
                case 30
                    tl=readtable("Book1.xlsx","Sheet",6);
                case 57
                    tl=readtable("Book1.xlsx","Sheet",8);
            end
            linedata=table2array(tl);

            V=busdata(:,3);
            type=busdata(:,2);
            bus = busdata(:,1);
            bMVA = 100;
            Qmin = busdata(:,9)/bMVA;
            Qmax = busdata(:,10)/bMVA;
            fb = linedata(:,1);             % From bus number...
            tb = linedata(:,2);             % To bus number...
            nl = length(fb);                % No. of Branches..
            loading_limit=linedata(:,end);
            
            for m = 1:nl
                p = fb(m); q = tb(m);
                MVA_Sij1=sqrt((real(Sij1(p,q))).^2+(imag(Sij1(p,q))).^2);
                if MVA_Sij1<loading_limit(m)*0.7
                    nr1={num2str(p) num2str(q) real(Sij1(p,q)) imag(Sij1(p,q)) abs(loading_limit(m)-MVA_Sij1)*100/abs(loading_limit(m))};
                    app.UITable6_7.Data=[app.UITable6_7.Data;nr1];
                end
            end
        end

        % Button pushed function: ShowAbnormalReportButton_4
        function ShowAbnormalReportButton_4Pushed(app, event)
            nbus=str2num(app.ChooseTheNumberofBusesDropDown.Value);
            [iter,telapsed,mismatch,v_error,dat1,Sij1,Lij1] = newton_raphson(app,nbus);
            [iter2,telapsed2,mismatch2,v_error2,dat2,Sij2,Lij2] = snr2(app,nbus);

            switch nbus
                case 4
                    t=readtable("Book1.xlsx","Sheet",1);
                case 14
                    t=readtable("Book1.xlsx","Sheet",3);
                case 30
                    t=readtable("Book1.xlsx","Sheet",5);
                case 57
                    t=readtable("Book1.xlsx","Sheet",7);
            end
            busdata=table2array(t);

            switch nbus
                case 4
                    tl=readtable("Book1.xlsx","Sheet",2);
                case 14
                    tl=readtable("Book1.xlsx","Sheet",4);
                case 30
                    tl=readtable("Book1.xlsx","Sheet",6);
                case 57
                    tl=readtable("Book1.xlsx","Sheet",8);
            end
            linedata=table2array(tl);

            V=busdata(:,3);
            type=busdata(:,2);
            bus = busdata(:,1);
            bMVA = 100;
            Qmin = busdata(:,9)/bMVA;
            Qmax = busdata(:,10)/bMVA;
            fb = linedata(:,1);             % From bus number...
            tb = linedata(:,2);             % To bus number...
            nl = length(fb);                % No. of Branches..
            loading_limit=linedata(:,end);
            
            for m = 1:nl
                p = fb(m); q = tb(m);
                MVA_Sij1=sqrt((real(Sij1(p,q))).^2+(imag(Sij1(p,q))).^2);
                if MVA_Sij1>loading_limit(m)
                    nr1={num2str(p) num2str(q) real(Sij1(p,q)) imag(Sij1(p,q)) abs(loading_limit(m)-MVA_Sij1)*100/abs(loading_limit(m))};
                    app.UITable6_8.Data=[app.UITable6_8.Data;nr1];
                end
            end
        end

        % Button pushed function: ShowSummaryReportButton
        function ShowSummaryReportButtonPushed(app, event)
            nbus=str2num(app.ChooseTheNumberofBusesDropDown.Value);
            [iter,telapsed,mismatch,v_error,dat1,Sij1,Lij1] = newton_raphson(app,nbus);
            [iter2,telapsed2,mismatch2,v_error2,dat2,Sij2,Lij2] = snr2(app,nbus);

            switch nbus
                case 4
                    t=readtable("Book1.xlsx","Sheet",1);
                case 14
                    t=readtable("Book1.xlsx","Sheet",3);
                case 30
                    t=readtable("Book1.xlsx","Sheet",5);
                case 57
                    t=readtable("Book1.xlsx","Sheet",7);
            end
            busdata=table2array(t);

            switch nbus
                case 4
                    tl=readtable("Book1.xlsx","Sheet",2);
                case 14
                    tl=readtable("Book1.xlsx","Sheet",4);
                case 30
                    tl=readtable("Book1.xlsx","Sheet",6);
                case 57
                    tl=readtable("Book1.xlsx","Sheet",8);
            end
            linedata=table2array(tl);

            V=busdata(:,3);
            type=busdata(:,2);
            bus = busdata(:,1);
            bMVA = 100;
            Qmin = busdata(:,9)/bMVA;
            Qmax = busdata(:,10)/bMVA;
            fb = linedata(:,1);             % From bus number...
            tb = linedata(:,2);             % To bus number...
            nl = length(fb);                % No. of Branches..
            loading_limit=linedata(:,end);
            
            Pg_total=sum(dat1(:,6));
            Qg_total=sum(dat1(:,7));
            LoadP_total=sum(dat1(:,8));
            LoadQ_total=sum(dat1(:,9));
            LossP_total=sum(real(Lij1));
            LossQ_total=sum(imag(Lij1));
            
            nr1={'Total Generation' Pg_total Qg_total};
            app.UITable6_9.Data=[app.UITable6_9.Data;nr1];
            nr2={'Total Load' LoadP_total LoadQ_total};
            app.UITable6_9.Data=[app.UITable6_9.Data;nr2];
            nr3={'Total Losses' LossP_total LossQ_total};
            app.UITable6_9.Data=[app.UITable6_9.Data;nr3];
            nr4={'---------------------------------------------------------' '---------------------------------------------------------' '---------------------------------------------------------'};
            app.UITable6_9.Data=[app.UITable6_9.Data;nr4];
            nr5={'Mismatches' Pg_total-LossP_total-LoadP_total Qg_total-LoadQ_total-LossQ_total};
            app.UITable6_9.Data=[app.UITable6_9.Data;nr5];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @Line_DataButtonPushed, true);
            app.ProjectPanel = uipanel(app.UIFigure);
            app.ProjectPanel.Title = 'Comparison Between Load Flow using Classical Newton Raphson and Simplified Newton Raphson ';
            app.UIAxes = uiaxes(app.ProjectPanel);
            title(app.UIAxes, 'Bar graph of time per iteration')
            xlabel(app.UIAxes, 'Newton Raphson   SNR')
            ylabel(app.UIAxes, 'Time per iteration')
            zlabel(app.UIAxes, 'Z')
            app.UITable3 = uitable(app.ProjectPanel);
            app.UITable3.ColumnName = {'From |Bus'; 'To |Bus'; 'P|(MW)'; 'Q|(MVAR)'; 'From |Bus'; 'To | Bus'; 'P|(MW)'; 'Q|(MVAR)'; 'P_loss|(MW)'; 'Q_loss|(MW)'};
            app.UITable3.RowName = {};
            app.UITable2 = uitable(app.ProjectPanel);
            app.UITable2.ColumnName = {'From | Bus'; 'To   |   Bus'; 'R| (pu)'; 'X |(pu)'; 'B/2  |  (pu)'; 'X''mer  |  Tap(a)'; 'Loading Limit | (MVA)'};
            app.UITable2.RowName = {};
            app.ReadLineDataButton = uibutton(app.ProjectPanel, 'push');
            app.ReadLineDataButton.ButtonPushedFcn = createCallbackFcn(app, @Line_DataButtonPushed, true);
            app.ReadLineDataButton.Text = 'Read Line Data';
            app.UITable = uitable(app.ProjectPanel);
            app.UITable.ColumnName = {'Bus No.'; 'Type'; 'V|(pu)'; 'Angle|(Degree)'; 'PGi|(MW)'; 'QGi|(MVAR)'; 'PLi |(MW)'; 'QLi |(MVAR)'; 'Qmin|(MVAR)'; 'Qmax|(MVAR)'};
            app.UITable.RowName = {};
            app.ReadBusDataButton = uibutton(app.ProjectPanel, 'push');
            app.ReadBusDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReadBusDataButtonPushed, true);
            app.ReadBusDataButton.Text = 'Read Bus Data';
            app.TextArea = uitextarea(app.ProjectPanel);
            app.TextArea.Value = {'Bus Data:'};
            app.PerformAnalysisButton = uibutton(app.ProjectPanel, 'push');
            app.PerformAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @PerformAnalysisButtonPushed, true);
            app.PerformAnalysisButton.Text = 'Perform Analysis';
            app.UITable4 = uitable(app.ProjectPanel);
            app.UITable4.ColumnName = {'From |Bus'; 'To |Bus'; 'P|(MW)'; 'Q|(MVAR)'; 'From |Bus'; 'To | Bus'; 'P|(MW)'; 'Q|(MVAR)'; 'P_loss|(MW)'; 'Q_loss|(MW)'};
            app.UITable4.RowName = {};
            app.EditField = uieditfield(app.ProjectPanel, 'text');
            app.EditField.Value = 'Using Simplified Newton Raphson:';
            app.EditField_2 = uieditfield(app.ProjectPanel, 'text');
            app.EditField_2.Value = 'Using Newton Raphson:';
            app.EditField_3 = uieditfield(app.ProjectPanel, 'text');
            app.EditField_3.Value = 'Comparison:';
            app.PerformAnalysisButton_2 = uibutton(app.ProjectPanel, 'push');
            app.PerformAnalysisButton_2.ButtonPushedFcn = createCallbackFcn(app, @PerformAnalysisButton_2Pushed2, true);
            app.PerformAnalysisButton_2.Text = 'Perform Analysis';
            app.ShowComparisonButton = uibutton(app.ProjectPanel, 'push');
            app.ShowComparisonButton.ButtonPushedFcn = createCallbackFcn(app, @ShowComparisonButtonPushed, true);
            app.ShowComparisonButton.Text = 'Show Comparison';
            app.UITable5 = uitable(app.ProjectPanel);
            app.UITable5.ColumnName = {'Method'; 'No Of Iteration'; 'Time Elapsed'; 'Time Per Iteration'};
            app.UITable5.RowName = {};
            app.EditField_4 = uieditfield(app.ProjectPanel, 'text');
            app.EditField_4.Value = 'Line Data:';
            app.ChooseTheNumberofBusesDropDownLabel = uilabel(app.ProjectPanel);
            app.ChooseTheNumberofBusesDropDownLabel.Text = 'Choose The Number of Buses';
            app.ChooseTheNumberofBusesDropDown = uidropdown(app.ProjectPanel);
            app.ChooseTheNumberofBusesDropDown.Items = {'4', '14', '30', '57'};
            app.ChooseTheNumberofBusesDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseTheNumberofBusesDropDownValueChanged, true);
            app.ChooseTheNumberofBusesDropDown.Value = '4';
            app.DescriptionofTypeLabel = uilabel(app.ProjectPanel);
            app.DescriptionofTypeLabel.Text = {'Description of Type:'; '1) Slack Bus'; '2) PV Bus - Load or/and Generator'; '3) PQ Bus - Load only'};
            app.Label = uilabel(app.ProjectPanel);
            app.Label.Text = 'Now, we can see that all the values match perfectly for these two types of methods. We will now see different reports:';
            app.EditField_5 = uieditfield(app.ProjectPanel, 'text');
            app.EditField_5.Value = 'Bus Report:';
            app.UITable6 = uitable(app.ProjectPanel);
            app.UITable6.ColumnName = {'Bus ID'; 'V| (pu)'; 'Angle    | (Degree)'; 'P Gen   | (MW)'; 'Q Gen   | (MVAR)'; 'P Load   | (MW)'; 'Q Load|(MW)'};
            app.UITable6.RowName = {};
            app.ShowBusReportButton = uibutton(app.ProjectPanel, 'push');
            app.ShowBusReportButton.ButtonPushedFcn = createCallbackFcn(app, @ShowBusReportButtonPushed, true);
            app.ShowBusReportButton.Text = 'Show Bus Report';
            app.EditField_6 = uieditfield(app.ProjectPanel, 'text');
            app.EditField_6.Value = 'Generator Report:';
            app.UITable6_2 = uitable(app.ProjectPanel);
            app.UITable6_2.ColumnName = {'Generator ID'; 'Bus ID'; 'P      | (MW)'; 'Q     | (MVAR)'; 'S    | (MVA)'; 'PF     |  %'; 'Q max    | (MVAR)'; 'Q min   | (MVAR)'};
            app.UITable6_2.RowName = {};
            app.ShowGeneratorReportButton = uibutton(app.ProjectPanel, 'push');
            app.ShowGeneratorReportButton.ButtonPushedFcn = createCallbackFcn(app, @ShowGeneratorReportButtonPushed, true);
            app.ShowGeneratorReportButton.Text = 'Show Generator Report';
            app.EditField_7 = uieditfield(app.ProjectPanel, 'text');
            app.EditField_7.Value = 'Load Report:';
            app.UITable6_3 = uitable(app.ProjectPanel);
            app.UITable6_3.ColumnName = {'Load ID'; 'Bus ID'; 'P      | (MW)'; 'Q     |   (MVAR)'; 'S    |  (MVAR)'; 'PF     |   %'};
            app.UITable6_3.RowName = {};
            app.ShowLoadReportButton = uibutton(app.ProjectPanel, 'push');
            app.ShowLoadReportButton.ButtonPushedFcn = createCallbackFcn(app, @ShowLoadReportButtonPushed, true);
            app.ShowLoadReportButton.Text = 'Show Load Report';
            app.EditField_8 = uieditfield(app.ProjectPanel, 'text');
            app.EditField_8.Value = 'Line Report:';
            app.UITable6_4 = uitable(app.ProjectPanel);
            app.UITable6_4.ColumnName = {'Line ID'; 'From|Bus'; 'To |Bus'; 'P     | (MW)'; 'Q    | (MVAR)'; 'S     | (MVA)'; 'PF   | %'; 'Loading | %'; 'P Losses | (MW)'; 'Q Losses |(MVAR)'};
            app.UITable6_4.RowName = {};
            app.ShowLineReportButton = uibutton(app.ProjectPanel, 'push');
            app.ShowLineReportButton.ButtonPushedFcn = createCallbackFcn(app, @ShowLineReportButtonPushed, true);
            app.ShowLineReportButton.Text = 'Show Line Report';
            app.AbnormalReportLabel = uilabel(app.ProjectPanel);
            app.AbnormalReportLabel.Text = 'Abnormal Report';
            app.EditField_9 = uieditfield(app.ProjectPanel, 'text');
            app.EditField_9.Value = 'Buses Outside Voltage Limit:';
            app.UITable6_5 = uitable(app.ProjectPanel);
            app.UITable6_5.ColumnName = {'Bus No.| Bus'; 'Loading Condition'; 'Percentage Loaded |%'};
            app.UITable6_5.RowName = {};
            app.ShowAbnormalReportButton = uibutton(app.ProjectPanel, 'push');
            app.ShowAbnormalReportButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAbnormalReportButtonPushed, true);
            app.ShowAbnormalReportButton.Text = 'Show Abnormal Report';
            app.EditField_10 = uieditfield(app.ProjectPanel, 'text');
            app.EditField_10.Value = 'Generators At Reactive Limit:';
            app.UITable6_6 = uitable(app.ProjectPanel);
            app.UITable6_6.ColumnName = {'Generator ID'; 'From| Bus'; 'P-Gen  | (MW)'; 'Q-Gen  | (MVAR)'; 'Q-Min  |  (MVAR)'; 'Q-Max  | (MVAR)'; 'Percentage Outside Limit    |%'};
            app.UITable6_6.RowName = {};
            app.ShowAbnormalReportButton_2 = uibutton(app.ProjectPanel, 'push');
            app.ShowAbnormalReportButton_2.ButtonPushedFcn = createCallbackFcn(app, @ShowAbnormalReportButton_2Pushed, true);
            app.ShowAbnormalReportButton_2.Text = 'Show Abnormal Report';
            app.EditField_11 = uieditfield(app.ProjectPanel, 'text');
            app.EditField_11.Value = 'Underloaded Lines and Cables:';
            app.UITable6_7 = uitable(app.ProjectPanel);
            app.UITable6_7.ColumnName = {'From|Bus'; 'To | Bus'; 'P    | (MW)'; 'Q    |(MVAR)'; 'Percentage Underloaded|%'};
            app.UITable6_7.RowName = {};
            app.ShowAbnormalReportButton_3 = uibutton(app.ProjectPanel, 'push');
            app.ShowAbnormalReportButton_3.ButtonPushedFcn = createCallbackFcn(app, @ShowAbnormalReportButton_3Pushed, true);
            app.ShowAbnormalReportButton_3.Text = 'Show Abnormal Report';
            app.EditField_12 = uieditfield(app.ProjectPanel, 'text');
            app.EditField_12.Value = 'Overloaded Lines and Cables:';
            app.UITable6_8 = uitable(app.ProjectPanel);
            app.UITable6_8.ColumnName = {'From|Bus'; 'To | Bus'; 'P    | (MW)'; 'Q    |(MVAR)'; 'Percentage Overloaded|%'};
            app.UITable6_8.RowName = {};
            app.ShowAbnormalReportButton_4 = uibutton(app.ProjectPanel, 'push');
            app.ShowAbnormalReportButton_4.ButtonPushedFcn = createCallbackFcn(app, @ShowAbnormalReportButton_4Pushed, true);
            app.ShowAbnormalReportButton_4.Text = 'Show Abnormal Report';
            app.SummAryReportLabel = uilabel(app.ProjectPanel);
            app.SummAryReportLabel.Text = 'SummAry Report:';
            app.BasePower100MVALabel = uilabel(app.ProjectPanel);
            app.BasePower100MVALabel.Text = {'--------------------------------'; 'Base Power = 100MVA'; '--------------------------------'};
            app.UITable6_9 = uitable(app.ProjectPanel);
            app.UITable6_9.ColumnName = {'Summary Data'; 'Active Power  | (MW)'; 'Reactive Power  | (MVAR)'};
            app.UITable6_9.RowName = {};
            app.ShowSummaryReportButton = uibutton(app.ProjectPanel, 'push');
            app.ShowSummaryReportButton.ButtonPushedFcn = createCallbackFcn(app, @ShowSummaryReportButtonPushed, true);
            app.ShowSummaryReportButton.Text = 'Show Summary Report';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project_dropdownpart

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end